classdef app1 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        MathsSkills110Label             matlab.ui.control.Label
        CodingSkills110Label            matlab.ui.control.Label
        NetworkingSkills110Label        matlab.ui.control.Label
        EmbeddedSysSkills110Label       matlab.ui.control.Label
        DatabaseSkills110Label          matlab.ui.control.Label
        DecisionMakingProgrammeLabel    matlab.ui.control.Label
        CalculateButton                 matlab.ui.control.Button
        CryptographyEditFieldLabel      matlab.ui.control.Label
        CryptographyEditField           matlab.ui.control.NumericEditField
        MachineLearningEditFieldLabel   matlab.ui.control.Label
        MachineLearningEditField        matlab.ui.control.NumericEditField
        EmbeddedDesignEditFieldLabel    matlab.ui.control.Label
        EmbeddedDesignEditField         matlab.ui.control.NumericEditField
        CloudComputingEditFieldLabel    matlab.ui.control.Label
        CloudComputingEditField         matlab.ui.control.NumericEditField
        IOTEditFieldLabel               matlab.ui.control.Label
        IOTEditField                    matlab.ui.control.NumericEditField
        DropDown                        matlab.ui.control.DropDown
        DropDown_2                      matlab.ui.control.DropDown
        DropDown_3                      matlab.ui.control.DropDown
        DropDown_4                      matlab.ui.control.DropDown
        DropDown_5                      matlab.ui.control.DropDown
        EnteryourskilllevelsLabel       matlab.ui.control.Label
        EditField                       matlab.ui.control.EditField
        EditField_2                     matlab.ui.control.EditField
        EditField_3                     matlab.ui.control.EditField
        EditField_4                     matlab.ui.control.EditField
        EditField_5                     matlab.ui.control.EditField
        Label                           matlab.ui.control.Label
        RecommendedSubjectsTextAreaLabel  matlab.ui.control.Label
        RecommendedSubjectsTextArea     matlab.ui.control.TextArea
        PercentageofSubjectSkillsLabel  matlab.ui.control.Label
    end


    properties (Access = private)
    end

    methods (Access = private)
    
        
        function Sorted(app)
            subject(1).name = "Cryptography";
            subject(1).percentage = app.CryptographyEditField.Value;
          
            
            subject(2).name = "Machine Learning";
            subject(2).percentage = app.MachineLearningEditField.Value;
            
            subject(3).name = "Embedded Design";
            subject(3).percentage = app.EmbeddedDesignEditField.Value;
            
            subject(4).name = "Cloud Computing";
            subject(4).percentage = app.CloudComputingEditField.Value;
            
            subject(5).name = "IOT";
            subject(5).percentage = app.IOTEditField.Value;
            b={};
            
            for i = 1:5
               percentages(i) = (subject(i).percentage); 
            end
            
            [M,Idx] = max(percentages);
            subName = {};
            name1 = " ";
            for i = 1:5 
                if percentages(i) == M
                    subName{i,1} = char(sprintf(subject(i).name));
                    app.RecommendedSubjectsTextArea.Value =(subName);
                else
                    subName{i,1} = char(sprintf(name1));
                    app.RecommendedSubjectsTextArea.Value =(subName);
                end
            end
                            
          % sub = subject(Idx).name;
          %  app.RecommendedSubjectsTextArea.Value =sub;
        end
        
    end


    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            
        end

        % Callback function
        function CryptographyButtonPushed(app, event)
            in1 = app.EditField.Value;
            in2 = app.EditField_2.Value;
            in3 = app.EditField_3.Value;
            in4 = app.EditField_4.Value;
            in5 = app.EditField_5.Value;
             Arr = ([in1, in2, in3, in4, in5]);
           % [file1,path] = uigetfile('D:\matlab project\pprogramme\crypto_network_secu.fis');
            fis1 = readfis('D:\matlab project\pprogramme\crypto_network_secu.fis');
            out1 = evalfis(Arr,fis1);
            app.PercentageEditField.Value = num2str(out);
            app.ResearchSubjectEditField.Value = "Cryptography";
            
            
        end

        % Button pushed function: CalculateButton
        function CalculateButtonPushed(app, event)
            in1 = str2double(app.DropDown.Value);
            in2 = str2double(app.DropDown_2.Value);
            in3 = str2double(app.DropDown_3.Value);
            in4 = str2double(app.DropDown_4.Value);
            in5 = str2double(app.DropDown_5.Value);
            
          %  in1 = app.EditField.Value;
         %   in2 = app.EditField_2.Value;
          %  in3 = app.EditField_3.Value;
          %  in4 = app.EditField_4.Value;
          %  in5 = app.EditField_5.Value;
            Arr = ([in1, in2, in3, in4, in5]);
            fis1 = readfis('crypto_network_secu.fis');
            out1 = evalfis(Arr,fis1);
            fis2 = readfis('ML.fis');
            out2 = evalfis(Arr,fis2);
            fis3 = readfis('Emb_process.fis');
            out3 = evalfis(Arr,fis3);
            fis4 = readfis('clould_computing.fis');
            out4 = evalfis(Arr,fis4);
            fis5 = readfis('IOT.fis');
            out5 = evalfis(Arr,fis5);
            app.CryptographyEditField.Value = out1;
            app.MachineLearningEditField.Value = out2;
            app.EmbeddedDesignEditField.Value = out3;
            app.CloudComputingEditField.Value = out4;
            app.IOTEditField.Value = out5;
            Sorted(app);
            
            
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure;
            app.UIFigure.Name = 'UI Figure';
            app.MathsSkills110Label = uilabel(app.UIFigure);
            app.MathsSkills110Label.Text = 'Maths Skills(1-10)';
            app.CodingSkills110Label = uilabel(app.UIFigure);
            app.CodingSkills110Label.Text = 'Coding  Skills(1-10)';
            app.NetworkingSkills110Label = uilabel(app.UIFigure);
            app.NetworkingSkills110Label.Text = 'Networking Skills(1-10)';
            app.EmbeddedSysSkills110Label = uilabel(app.UIFigure);
            app.EmbeddedSysSkills110Label.Text = 'Embedded Sys Skills(1-10)';
            app.DatabaseSkills110Label = uilabel(app.UIFigure);
            app.DatabaseSkills110Label.Text = 'Database Skills(1-10)';
            app.DecisionMakingProgrammeLabel = uilabel(app.UIFigure);
            app.DecisionMakingProgrammeLabel.Text = 'Decision Making Programme';
            app.CalculateButton = uibutton(app.UIFigure, 'push');
            app.CalculateButton.ButtonPushedFcn = createCallbackFcn(app, @CalculateButtonPushed, true);
            app.CalculateButton.Text = 'Calculate';
            app.CryptographyEditFieldLabel = uilabel(app.UIFigure);
            app.CryptographyEditFieldLabel.Text = 'Cryptography';
            app.CryptographyEditField = uieditfield(app.UIFigure, 'numeric');
            app.MachineLearningEditFieldLabel = uilabel(app.UIFigure);
            app.MachineLearningEditFieldLabel.Text = 'Machine Learning';
            app.MachineLearningEditField = uieditfield(app.UIFigure, 'numeric');
            app.EmbeddedDesignEditFieldLabel = uilabel(app.UIFigure);
            app.EmbeddedDesignEditFieldLabel.Text = 'Embedded Design';
            app.EmbeddedDesignEditField = uieditfield(app.UIFigure, 'numeric');
            app.CloudComputingEditFieldLabel = uilabel(app.UIFigure);
            app.CloudComputingEditFieldLabel.Text = 'Cloud Computing';
            app.CloudComputingEditField = uieditfield(app.UIFigure, 'numeric');
            app.IOTEditFieldLabel = uilabel(app.UIFigure);
            app.IOTEditFieldLabel.Text = 'IOT';
            app.IOTEditField = uieditfield(app.UIFigure, 'numeric');
            app.DropDown = uidropdown(app.UIFigure);
            app.DropDown.Items = {'None', '1', '2', '3', '4', '5', '6', '7', '8', '9', '10'};
            app.DropDown.Value = 'None';
            app.DropDown_2 = uidropdown(app.UIFigure);
            app.DropDown_2.Items = {'None', '1', '2', '3', '4', '5', '6', '7', '8', '9', '10'};
            app.DropDown_2.Value = 'None';
            app.DropDown_3 = uidropdown(app.UIFigure);
            app.DropDown_3.Items = {'None', '1', '2', '3', '4', '5', '6', '7', '8', '9', '10'};
            app.DropDown_3.Value = 'None';
            app.DropDown_4 = uidropdown(app.UIFigure);
            app.DropDown_4.Items = {'None', '1', '2', '3', '4', '5', '6', '7', '8', '9', '10'};
            app.DropDown_4.Value = 'None';
            app.DropDown_5 = uidropdown(app.UIFigure);
            app.DropDown_5.Items = {'None', '1', '2', '3', '4', '5', '6', '7', '8', '9', '10'};
            app.DropDown_5.Value = 'None';
            app.EnteryourskilllevelsLabel = uilabel(app.UIFigure);
            app.EnteryourskilllevelsLabel.Text = 'Enter your skill levels ';
            app.EditField = uieditfield(app.UIFigure, 'text');
            app.EditField.Value = '%';
            app.EditField_2 = uieditfield(app.UIFigure, 'text');
            app.EditField_2.Value = '%';
            app.EditField_3 = uieditfield(app.UIFigure, 'text');
            app.EditField_3.Value = '%';
            app.EditField_4 = uieditfield(app.UIFigure, 'text');
            app.EditField_4.Value = '%';
            app.EditField_5 = uieditfield(app.UIFigure, 'text');
            app.EditField_5.Value = '%';
            app.Label = uilabel(app.UIFigure);
            app.Label.Text = 'Press "Calculate" Button ';
            app.RecommendedSubjectsTextAreaLabel = uilabel(app.UIFigure);
            app.RecommendedSubjectsTextAreaLabel.Text = 'Recommended Subjects';
            app.RecommendedSubjectsTextArea = uitextarea(app.UIFigure);
            app.PercentageofSubjectSkillsLabel = uilabel(app.UIFigure);
            app.PercentageofSubjectSkillsLabel.Text = 'Percentage of Subject Skills';
        end
    end

    methods (Access = public)

        % Construct app
        function app = app1

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end